classdef StickBreakingDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SumLabel          matlab.ui.control.Label
        KLabel            matlab.ui.control.Label
        AlphaLabel        matlab.ui.control.Label
        RerunButton       matlab.ui.control.Button
        KSlider           matlab.ui.control.Slider
        KSliderLabel      matlab.ui.control.Label
        AlphaSlider       matlab.ui.control.Slider
        AlphaSliderLabel  matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function update(app)
            alpha = app.AlphaSlider.Value;
            k = round(app.KSlider.Value);

            app.AlphaLabel.Text = num2str(alpha, 3);
            app.KLabel.Text = num2str(k);

            w = stickBreaking(alpha, k);

            s = sum(w);
            app.SumLabel.Text = sprintf("sum = %.2f", s);

            bar(app.UIAxes, range(length(w)), w);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            app.update();
        end

        % Button pushed function: RerunButton
        function RerunButtonPushed(app, event)
            app.update();
        end

        % Value changed function: KSlider
        function KSliderValueChanged(app, event)
            app.update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'cool');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dirichlet Process as Stick Breaking')
            xlabel(app.UIAxes, 'Stick Breaking Iterations')
            ylabel(app.UIAxes, 'Stick Weight')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            colormap(app.UIAxes, 'cool')
            app.AlphaSliderLabel = uilabel(app.UIFigure);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.UIFigure);
            app.AlphaSlider.Limits = [1 50];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 1;
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [1 300];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged, true);
            app.KSlider.Value = 10;
            app.RerunButton = uibutton(app.UIFigure, 'push');
            app.RerunButton.ButtonPushedFcn = createCallbackFcn(app, @RerunButtonPushed, true);
            app.RerunButton.Text = 'Rerun';
            app.AlphaLabel = uilabel(app.UIFigure);
            app.AlphaLabel.Text = 'alpha';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'k';
            app.SumLabel = uilabel(app.UIFigure);
            app.SumLabel.Text = 'sum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StickBreakingDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end